classdef ElectroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResultadoLabel                matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        amLabel                       matlab.ui.control.Label
        q3Field                       matlab.ui.control.NumericEditField
        q3CLabel                      matlab.ui.control.Label
        q2Field                       matlab.ui.control.NumericEditField
        q2CLabel                      matlab.ui.control.Label
        q1Field                       matlab.ui.control.NumericEditField
        q1CLabel                      matlab.ui.control.Label
        CalculartrabajoButton         matlab.ui.control.Button
        CalcularcampoelectricoButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularcampoelectricoButton
        function CalcularcampoelectricoButtonPushed(app, event)
             % Obtener valores de la interfaz
            q1 = app.q1Field.Value;
            q2 = app.q2Field.Value;
            q3 = app.q3Field.Value;
            a = app.aField.Value;
        
            % Llamar al script del campo eléctrico
            [y_points, E_mags] = campoElectrico(q1, q2, q3, a);
        
            % Graficar dentro del UIAxes de la App
            plot(app.UIAxes, y_points, E_mags, 'o-b','LineWidth',2);
            xlabel(app.UIAxes,'y (m)');
            ylabel(app.UIAxes,'|E| (N/C)');
            title(app.UIAxes,'Campo eléctrico en la altura');
        end

        % Button pushed function: CalculartrabajoButton
        function CalculartrabajoButtonPushed(app, event)
            % Obtener valores
            q1 = app.q1Field.Value;
            q2 = app.q2Field.Value;
            q3 = app.q3Field.Value;
            a = app.aField.Value;
        
            % Llamar al script del trabajo
            W = trabajoCargas(q1, q2, q3, a);
        
            % Mostrar resultado en un Label
            app.ResultadoLabel.Text = sprintf('Trabajo: %.3e J', W);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CalcularcampoelectricoButton = uibutton(app.UIFigure, 'push');
            app.CalcularcampoelectricoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularcampoelectricoButtonPushed, true);
            app.CalcularcampoelectricoButton.Text = 'Calcular campo electrico';
            app.CalculartrabajoButton = uibutton(app.UIFigure, 'push');
            app.CalculartrabajoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculartrabajoButtonPushed, true);
            app.CalculartrabajoButton.Text = 'Calcular trabajo';
            app.q1CLabel = uilabel(app.UIFigure);
            app.q1CLabel.Text = 'q1 (C):';
            app.q1Field = uieditfield(app.UIFigure, 'numeric');
            app.q2CLabel = uilabel(app.UIFigure);
            app.q2CLabel.Text = 'q2 (C) :';
            app.q2Field = uieditfield(app.UIFigure, 'numeric');
            app.q3CLabel = uilabel(app.UIFigure);
            app.q3CLabel.Text = 'q3 (C): ';
            app.q3Field = uieditfield(app.UIFigure, 'numeric');
            app.amLabel = uilabel(app.UIFigure);
            app.amLabel.Text = 'a (m): ';
            app.aField = uieditfield(app.UIFigure, 'numeric');
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Trabajo: ---';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end